classdef app_dualMicArrayParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Label       matlab.ui.control.Label
        dis         matlab.ui.control.NumericEditField
        mLabel      matlab.ui.control.Label
        HighFreq    matlab.ui.control.Label
        LowFreq     matlab.ui.control.Label
        Button      matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        Slider      matlab.ui.control.Slider
        RightPanel  matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        UIAxes2     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        soundSpeed = 340; % sound speed
        frequency = 0:100:8000; % frequency range 0-8kHz
        r
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.r =  rectangle(app.UIAxes2,'Position',[0,0,1,1],'FaceColor',[0 .5 .5]);
            app.UIAxes2.XLim = [0 1];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {471, 471};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            distance = app.dis.Value;
            
            %% calc low-frequency
            t = 2*pi*app.frequency*distance/app.soundSpeed;
            y = sinc(t);
            plot(app.UIAxes,app.frequency,y);
            
            for ii=1:length(app.frequency)-1
                if (y(ii)>0&&y(ii+1)<0) || y(ii)==0
                    app.LowFreq.Text = ['抑噪最低频：',num2str(app.frequency(ii)/1000),'kHz'];
                    break;
                end
            end
            
            %% calc high-frequency
            highfrequency = 0.5*app.soundSpeed/distance;
            app.HighFreq.Text = ['混叠频率：',num2str(highfrequency/1000),'kHz'];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            distance = app.dis.Value;
            freqNF = app.Slider.Value;
            lambda = app.soundSpeed/freqNF;
            Rmin = 0.62*(distance^3/lambda)^0.5;
            Rmax = 2*distance^2/lambda;
            app.r.Position = [Rmin,0,Rmax,1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '麦间距：';
            app.dis = uieditfield(app.LeftPanel, 'numeric');
            app.dis.Value = 0.035;
            app.mLabel = uilabel(app.LeftPanel);
            app.mLabel.Text = 'm';
            app.HighFreq = uilabel(app.LeftPanel);
            app.HighFreq.Text = '混叠频率：';
            app.LowFreq = uilabel(app.LeftPanel);
            app.LowFreq.Text = '抑噪最低频：';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '远近场频率：';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 8000];
            app.Slider.MajorTicks = [1000 2000 3000 4000 5000 6000 7000 8000];
            app.Slider.MajorTickLabels = {'1k', '2k', '3k', '4k', '5k', '6k', '7k', '8k'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '双麦相关性')
            xlabel(app.UIAxes, '频率')
            ylabel(app.UIAxes, '相关性')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [-0.5 1];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '远近场')
            xlabel(app.UIAxes2, '距离')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_dualMicArrayParam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end