classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FrequencyLabel      matlab.ui.control.Label
        FrequencyEditField  matlab.ui.control.NumericEditField
        MicLabel            matlab.ui.control.Label
        MicEditField        matlab.ui.control.NumericEditField
        DistanceLabel       matlab.ui.control.Label
        DistanceEditField   matlab.ui.control.NumericEditField
        kHzLabel            matlab.ui.control.Label
        mmLabel             matlab.ui.control.Label
        Button              matlab.ui.control.Button
        TargetDOALabel      matlab.ui.control.Label
        TargetDOAEditField  matlab.ui.control.NumericEditField
        DegreeLabel         matlab.ui.control.Label
        AliasingfrequencywaitingforcalcLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MicEditField.Value = 6;
            app.DistanceEditField.Value = 35;
            app.FrequencyEditField.Value = 3;
            app.TargetDOAEditField.Value = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            dec = pi/180;
            c   = 340;
            micNum = app.MicEditField.Value;
            dis    = app.DistanceEditField.Value;
            frequency = app.FrequencyEditField.Value;
            thetaRange = meshgrid(-90:90); 
            phiRange   = meshgrid(eps:2:360)';
            degree = app.TargetDOAEditField.Value;
            % calc Direction graph
            beta = 2*pi*dis*sin(thetaRange*dec)*frequency/c;
            betaTgt = 2*pi*dis*sin(degree*dec)*frequency/c;
            betaDiff = (beta-betaTgt)/2;
            G = abs(sin(micNum*betaDiff)./(micNum*sin(betaDiff))+0.1);
            % figure
            plot(app.UIAxes,thetaRange(1,:),G(1,:));
            fmax = c/(2*dis);
            app.AliasingfrequencywaitingforcalcLabel.Text = ['Aliasing frequency: ',num2str(fmax),'kHz'];
%             [x,y,z]=sph2cart(phiRange*dec,thetaRange*dec,G);
%             mesh(x,y,z);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MicLabel = uilabel(app.UIFigure);
            app.MicLabel.Text = 'Mic:';
            app.MicEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = 'Distance:';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.kHzLabel = uilabel(app.UIFigure);
            app.kHzLabel.Text = 'kHz';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.TargetDOALabel = uilabel(app.UIFigure);
            app.TargetDOALabel.Text = 'Target DOA:';
            app.TargetDOAEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegreeLabel = uilabel(app.UIFigure);
            app.DegreeLabel.Text = 'Degree';
            app.AliasingfrequencywaitingforcalcLabel = uilabel(app.UIFigure);
            app.AliasingfrequencywaitingforcalcLabel.Text = 'Aliasing frequency: waiting for calc';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'beamforming')
            xlabel(app.UIAxes, 'DOA')
            ylabel(app.UIAxes, 'G')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end