classdef app_fft_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        fsEditFieldLabel         matlab.ui.control.Label
        fsEditField              matlab.ui.control.NumericEditField
        FFTLenEditFieldLabel     matlab.ui.control.Label
        FFTLenEditField          matlab.ui.control.NumericEditField
        FFTIncEditFieldLabel     matlab.ui.control.Label
        FFTIncEditField          matlab.ui.control.NumericEditField
        Panel                    matlab.ui.container.Panel
        freq2binButton           matlab.ui.control.Button
        bin2freqButton           matlab.ui.control.Button
        binNoEditFieldLabel      matlab.ui.control.Label
        binNoEditField           matlab.ui.control.NumericEditField
        frequencyEditFieldLabel  matlab.ui.control.Label
        frequencyEditField       matlab.ui.control.NumericEditField
        hzLabel                  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        frameLabel               matlab.ui.control.Label
        frequencyBinLabel        matlab.ui.control.Label
        Calc                     matlab.ui.control.Button
        delayLabel               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bin2freqButton
        function bin2freqButtonPushed(app, event)
            freqFull = app.fsEditField.Value/2;
            binNum = app.FFTIncEditField.Value;
            bin = app.binNoEditField.Value;
            app.frequencyEditField.Value = freqFull/binNum*bin;
        end

        % Button pushed function: freq2binButton
        function freq2binButtonPushed(app, event)
            freqFull = app.fsEditField.Value/2;
            binNum = app.FFTIncEditField.Value;
            freq = app.frequencyEditField.Value;
            app.binNoEditField.Value = floor(freq/freqFull*binNum);
        end

        % Button pushed function: Calc
        function CalcPushed(app, event)
            fs = app.fsEditField.Value;
            freqFull = fs/2;
            binNum = app.FFTIncEditField.Value;
            fftLen = app.FFTLenEditField.Value;
            app.delayLabel.Text = ['时延：',num2str(1/fs*fftLen*1e3),'ms'];
            app.frameLabel.Text = ['帧长：',num2str(1/fs*binNum*1e3),'ms'];
            app.frequencyBinLabel.Text = ['频段：',num2str(freqFull/binNum),'Hz'];
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {432, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fsEditFieldLabel = uilabel(app.LeftPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.fsEditField.ValueDisplayFormat = '%.0f';
            app.fsEditField.Value = 16000;
            app.FFTLenEditFieldLabel = uilabel(app.LeftPanel);
            app.FFTLenEditFieldLabel.Text = 'FFTLen';
            app.FFTLenEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FFTLenEditField.Value = 512;
            app.FFTIncEditFieldLabel = uilabel(app.LeftPanel);
            app.FFTIncEditFieldLabel.Text = 'FFTInc';
            app.FFTIncEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FFTIncEditField.Value = 256;
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '频率频点转换';
            app.freq2binButton = uibutton(app.Panel, 'push');
            app.freq2binButton.ButtonPushedFcn = createCallbackFcn(app, @freq2binButtonPushed, true);
            app.freq2binButton.Text = '△';
            app.bin2freqButton = uibutton(app.Panel, 'push');
            app.bin2freqButton.ButtonPushedFcn = createCallbackFcn(app, @bin2freqButtonPushed, true);
            app.bin2freqButton.Text = '▽';
            app.binNoEditFieldLabel = uilabel(app.Panel);
            app.binNoEditFieldLabel.Text = 'bin No.';
            app.binNoEditField = uieditfield(app.Panel, 'numeric');
            app.frequencyEditFieldLabel = uilabel(app.Panel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.Panel, 'numeric');
            app.hzLabel = uilabel(app.LeftPanel);
            app.hzLabel.Text = 'hz';
            app.RightPanel = uipanel(app.GridLayout);
            app.frameLabel = uilabel(app.RightPanel);
            app.frameLabel.Text = '帧长：';
            app.frequencyBinLabel = uilabel(app.RightPanel);
            app.frequencyBinLabel.Text = '频段：';
            app.Calc = uibutton(app.RightPanel, 'push');
            app.Calc.ButtonPushedFcn = createCallbackFcn(app, @CalcPushed, true);
            app.Calc.Text = '计算';
            app.delayLabel = uilabel(app.RightPanel);
            app.delayLabel.Text = '时延：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_fft_parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end