classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        Label_7        matlab.ui.control.Label
        standDegree    matlab.ui.control.NumericEditField
        Label_9        matlab.ui.control.Label
        range_2        matlab.ui.control.NumericEditField
        Label_10       matlab.ui.control.Label
        speakerHigh_2  matlab.ui.control.NumericEditField
        Label_11       matlab.ui.control.Label
        micHigh_2      matlab.ui.control.NumericEditField
        realDegree     matlab.ui.control.Label
        Button         matlab.ui.control.Button
        RightPanel     matlab.ui.container.Panel
        Image          matlab.ui.control.Image
        Label_4        matlab.ui.control.Label
        range          matlab.ui.control.NumericEditField
        Label_5        matlab.ui.control.Label
        speakerHigh    matlab.ui.control.NumericEditField
        Label_6        matlab.ui.control.Label
        micHigh        matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: micHigh_2
        function micHigh_2ValueChanged(app, event)
            app.micHigh.Value = app.micHigh_2.Value;            
        end

        % Value changed function: speakerHigh_2
        function speakerHigh_2ValueChanged(app, event)
            app.speakerHigh.Value = app.speakerHigh_2.Value;            
        end

        % Value changed function: range_2
        function range_2ValueChanged(app, event)
            app.range.Value = app.range_2.Value;        
        end

        % Value changed function: micHigh
        function micHighValueChanged(app, event)
            app.micHigh_2.Value = app.micHigh.Value;          
        end

        % Value changed function: speakerHigh
        function speakerHighValueChanged(app, event)
            app.speakerHigh_2.Value = app.speakerHigh.Value;            
        end

        % Value changed function: range
        function rangeValueChanged(app, event)
            app.range_2.Value = app.range.Value;          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a       = [1,0,0];
            dec     = pi/180;
            theta   = app.standDegree.Value*dec;
            R       = app.range.Value;
            b(1) = R*cos(theta);
            b(2) = R*sin(theta);
            b(3) = app.micHigh.Value-app.speakerHigh.Value;
            theta_real = acos(a*b'/(norm(a)*norm(b)))/dec;
            app.realDegree.Text = ['实际方位：', num2str(theta_real)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '站立方位（度）';
            app.standDegree = uieditfield(app.LeftPanel, 'numeric');
            app.standDegree.Value = 30;
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '距离（米）';
            app.range_2 = uieditfield(app.LeftPanel, 'numeric');
            app.range_2.ValueChangedFcn = createCallbackFcn(app, @range_2ValueChanged, true);
            app.range_2.Value = 2;
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '人嘴高度（米）';
            app.speakerHigh_2 = uieditfield(app.LeftPanel, 'numeric');
            app.speakerHigh_2.ValueChangedFcn = createCallbackFcn(app, @speakerHigh_2ValueChanged, true);
            app.speakerHigh_2.Value = 1.65;
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = '麦克风高度（米）';
            app.micHigh_2 = uieditfield(app.LeftPanel, 'numeric');
            app.micHigh_2.ValueChangedFcn = createCallbackFcn(app, @micHigh_2ValueChanged, true);
            app.micHigh_2.Value = 1.8;
            app.realDegree = uilabel(app.LeftPanel);
            app.realDegree.Text = '实际方位：';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '换算';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = '图片2.png';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '距离（米）';
            app.range = uieditfield(app.RightPanel, 'numeric');
            app.range.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.range.Value = 2;
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '人嘴高度（米）';
            app.speakerHigh = uieditfield(app.RightPanel, 'numeric');
            app.speakerHigh.ValueChangedFcn = createCallbackFcn(app, @speakerHighValueChanged, true);
            app.speakerHigh.Value = 1.65;
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = '麦克风高度（米）';
            app.micHigh = uieditfield(app.RightPanel, 'numeric');
            app.micHigh.ValueChangedFcn = createCallbackFcn(app, @micHighValueChanged, true);
            app.micHigh.Value = 1.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end